classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OperationModeButtonGroup     matlab.ui.container.ButtonGroup
        VoiceCommandButton           matlab.ui.control.ToggleButton
        ManualButton                 matlab.ui.control.ToggleButton
        Waypoint2EditField           matlab.ui.control.EditField
        Waypoint2EditFieldLabel      matlab.ui.control.Label
        ChallengeButtonGroup         matlab.ui.container.ButtonGroup
        ChallengeCButton             matlab.ui.control.ToggleButton
        ChallengeBButton             matlab.ui.control.ToggleButton
        ChallengeAButton             matlab.ui.control.ToggleButton
        AngleGauge                   matlab.ui.control.SemicircularGauge
        AngleGaugeLabel              matlab.ui.control.Label
        StoppositionEditField        matlab.ui.control.EditField
        StoppositionEditFieldLabel   matlab.ui.control.Label
        ForceGauge                   matlab.ui.control.SemicircularGauge
        ForceLabel                   matlab.ui.control.Label
        ScoreLabel                   matlab.ui.control.Label
        ypositionLabel               matlab.ui.control.Label
        TimessccLabel                matlab.ui.control.Label
        NewCommandButton             matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        StartpositionEditField       matlab.ui.control.EditField
        StartpositionEditFieldLabel  matlab.ui.control.Label
        Waypoint1EditField           matlab.ui.control.EditField
        Waypoint1EditFieldLabel      matlab.ui.control.Label
        VelocityGauge                matlab.ui.control.SemicircularGauge
        VelocityGaugeLabel           matlab.ui.control.Label
        xpositionLabel               matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RandTimer          % Timer object
        PlotLine           % Line object
    end
    

    methods (Access = private)
    
        function RandTimerFcn(app,~,~)
            global g1
            global halt;


            global kitt
            global v
            global force
            global pos
            global time
            global status
            global angle
            global s_stop
            global noise;
            
            kitt %put semicolumn to not show in command line
            
            v(1) = 100*kitt.VectorX(2);
            v(2) = 100*kitt.VectorY(2);
            
            pos(1) = 100*kitt.VectorX(1); %convert axis
            pos(2) = -100*kitt.VectorY(1) + 600;
            
            status = kitt.status;
            time = kitt.time;
            initialTime = kitt.initialTime;
            finalTime = kitt.finalTime;
            
            dis2finish = -1*str2double(app.Waypoint1EditField.Value) + pos(2);
            
            if(noise == 1)
                pause(0.010 + rand(1)*0.02)%delay to simulate the sensor delay
            end
            global go;
            if(go == 1 && halt == 0)
                if(noise == 1)
                    pause(0.010 + rand(1)*0.02)%delay to simulate the delay in setting force
                end
                force = 10
            elseif(v(2) < 15 && halt == 1) %distance sensor
                if(noise == 1)
                    pause(0.010 + rand(1)*0.02)%delay to simulate the delay in setting force
                end
                
                force = 0
                if(v(2) < 0.01) % maybe add: dis2finish < 5 || 
                    kitt.status = 2;
                end
                
            elseif(pos(2) < s_stop && halt == 1)
                if(noise == 1)
                    pause(0.010 + rand(1)*0.02)%delay to simulate the delay in setting force
                end
                force = -10
            
            end
            kitt.force = force;


            v_mag = sqrt(v(1)^2 + v(2)^2); %calculate the magnitude of velocity & acceleration
                        
            app.VelocityGauge.Value = v_mag;
            value=sprintf('Velocity = %.2f cm/s',v_mag);
            app.VelocityLabel.Text = (value);
            
            if kitt.status ~= 2
                timeL = toc(initialTime);
            else
                disp("final time")
                timeL = toc(initialTime)-toc(finalTime);
            end
            value = sprintf('Time: %.2f s', timeL);
            app.TimessccLabel.Text = value;


            value=sprintf('X Position = %.2f m',pos(1));
            app.xpositionLabel.Text = (value);
            value=sprintf('Y Position = %.2f m',pos(2));
            app.ypositionLabel.Text = (value);
            

            if (status == -1)
            app.StatusLabel.Text = ('Status = crash occured');
            elseif (status == 1)
                app.StatusLabel.Text = ('Status = ON');
                %kitt.angle = angle;
                %kitt.force = force; % maybe later add to: function ForceKnobValueChanging(app, event)
            elseif(status == 0)
                app.StatusLabel.Text = ('Status = OFF');
            elseif(status == 2)
                app.StatusLabel.Text = ('Status = SUCCEEDED');
            end
            
            
            if(halt == 1)
                app.DistanceSensorsActivatedLabel.Visible = 'on';
            else
                app.DistanceSensorsActivatedLabel.Visible = 'off';
            end
            
            
            force = kitt.force; %update the force and angle after write
            angle = kitt.angle;
            
            app.ForceGauge.Value = force;


            app.DistancetoFinishLabel.Text = sprintf('Distance to Finish = %.2fcm',dis2finish);  

            %pos(end + 1) = ceil(xpos);
            
            plot(app.UIAxes,pos(1),pos(2),'b.')
            hold( app.UIAxes, 'on' )
            
            
            img = imread('car.png');             % Load a sample image 
            %img1 = imrotate(img,90);
            delete(g1);
            global car_visible;
            if (car_visible == 1)
            g1 = image(app.UIAxes,[pos(1)-20 pos(1)+20],[pos(2)-40 pos(2)+40],img);        % Plot the image
            end


       end        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %rectangle(app.UIAxes,'Position',[1,2,5,10],'FaceColor',[0.8 0.8 0.8],'EdgeColor','k',...
    %'LineWidth',3);
            %axis(app.UIAxes, 'off');
            
            waypoint_1_x = 20;
            waypoint_1_y = 100;
            waypoint_2_x = 20;
            waypoint_2_y = 200;
            
            global ini_p;
            ini_p = 0;
            
            global kitt
            kitt = model;
            global v;
            v = [0,0];
            global force;
            force = 0;
            global angle;
            angle = 90;
            
            kitt.force = force; %initial conditions
            kitt.angle = angle;
            
            global Timer_period;
            Timer_period = 0.076;
            
                app.KITTLamp.Color = 'red';
                        % Create timer object
            app.RandTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', Timer_period, ...                     % Period is 0.1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.RandTimerFcn);      % Specify callback function


%            patch(app.UIAxes,'vertices', [0, 600; 600, 600; 600, 595; 0, 595], ...
%           'faces', [1, 2, 3, 4], ...
%           'FaceColor', 'b', ...
%           'FaceAlpha', 0.5)
           
            hold(app.UIAxes, 'on');
            %plot(app.UIAxes,waypoint_1_x,waypoint_1_y,'r+')
            %plot(app.UIAxes,waypoint_2_x,waypoint_2_y,'r+')
            %plot(app.UIAxes,0,0,'rx')
            %plot(app.UIAxes,0,600,'rx')
            %plot(app.UIAxes,600,0,'rx')
            %plot(app.UIAxes,600,600,'rx')

            xlim(app.UIAxes,[0, 600])
            ylim(app.UIAxes,[0, 600])

            
            global first_ini;
            first_ini = 1;
            
            global halt;
            global noise;
            halt = 0;
            noise = 1;
            
            global go;
            go = 0;
            
            
            global car_visible;
            car_visible = 1;
            

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.RandTimer);
            delete(app)
        end

        % Callback function
        function velocityKnobValueChanged(app, event)
            value = app.velocityKnob.Value;
            global v
            v = value;
            value=sprintf('Velocity = %.2f',value);
            app.VelocityLabel.Text = (value);
        end

        % Callback function
        function KITTSwitchValueChanged(app, event)
            value = app.KITTSwitch.Value;
               global kitt
            if (strcmpi(value,'On') == 1)
                kitt.status = 1
                app.KITTLamp.Color = 'green';
            elseif (strcmpi(value,'Off') == 1)
                kitt.status = 0
                app.KITTLamp.Color = 'red';
            end
        end

        % Callback function
        function velocityKnobValueChanging(app, event)
            value = event.Value;

            global v
            v = value;
            value=sprintf('Velocity = %.2f',value);
            app.VelocityLabel.Text = (value);
        end

        % Callback function
        function ForceKnobValueChanging(app, event)
            
%             value = event.Value;
%             global force
%             global kitt
%             
%             value = ceil(value);
%             
%             fakeAxis=135:1:165;
%             realAxis=linspace(-10,10,length(fakeAxis));
%             conv=realAxis(find(value==fakeAxis));
% 
%             force = ceil(conv)
%             kitt.force=force;
        end

        % Callback function
        function crashButtonPushed(app, event)
            global kitt
            kitt.status = 1;
        end

        % Value changing function: StartpositionEditField
        function StartpositionEditFieldValueChanging(app, event)
            changingValue = event.Value;

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global kitt;
            global Timer_period;
%             start_value = str2double(app.StartpositionEditField.Value);
%             stop_value = str2double(app.Waypoint1EditField.Value);
%             yline(app.UIAxes,start_value,'--','Start Position');
%             hold(app.UIAxes, 'on' )
%             yline(app.UIAxes,stop_value,'-.','Stop position');
%             app.StartButton.Enable = 'off';
%             kitt.VectorY(1) = (-1*start_value + 600)*0.01;
%             kitt.VectorX(1) = 300*0.01;

out1 = regexp(app.StartpositionEditField.Value, ',', 'split')
out1 = str2double(out1)
out2 = regexp(app.Waypoint1EditField.Value, ',', 'split')
out2 = str2double(out2)
out3 = regexp(app.Waypoint2EditField.Value, ',', 'split')
out3 = str2double(out3)
out4 = regexp(app.StoppositionEditField.Value, ',', 'split')
out4 = str2double(out4)

waypoints = [double(out1(1)) double(out1(2)); ...
	double(out2(1)) double(out2(2)); ...
	double(out3(1)) double(out3(2)); ...
	double(out4(1)) double(out4(2))];
refPath = referencePathFrenet(waypoints);
connector = trajectoryGeneratorFrenet(refPath);

initState = [0 0 0 0 0 0];  % [S ds ddS L dL ddL]
termState = [1100 0 0 0 0 0]; % [S ds ddS L dL ddL]
[~,trajGlobal] = connect(connector,initState,termState,5);

            plot(app.UIAxes,waypoints(2,1),waypoints(2,2),'r+')
            plot(app.UIAxes,waypoints(3,1),waypoints(3,2),'r+')
            hold(app.UIAxes, 'on' )

x_coor = trajGlobal.Trajectory(:,1);
y_coor = trajGlobal.Trajectory(:,2);

            plot(app.UIAxes, x_coor, y_coor)
            hold(app.UIAxes, 'on' )
            
            
            %timer_period = 0.05

            %start(app.RandTimer);
        end

        % Button pushed function: NewCommandButton
        function NewCommandButtonPushed(app, event)
            Fs = 8000;
            win = 20e-3;
            threshold = 0.1;
            L = Fs*win;
            ov = 0.5*L;
            global force;
            random_name = load("mymodel.mat");
            model = random_name.model;
            mu_train = random_name.mu_train;
            sigma_train = random_name.sigma_train;
            

            recObj = audiorecorder(Fs,16,1); % create audio object, 16 bits resolution
            
            disp('Start speaking...')
            app.NewCommandButton.BackgroundColor = 'green';
            recordblocking(recObj, 0.5); % do a 2 second recording (blocking)
            disp('End of Recording.');
            app.NewCommandButton.BackgroundColor = 'white';
            y = getaudiodata(recObj);
            
            
            %plot(y)
            
            
            audiowrite("lastRecording.wav",y,Fs);
            
            x_test = ExtractFeat(y, Fs, L, ov, threshold);

            % standard normalize 
            x_test = (x_test-mu_train)./sigma_train;

            % predic
            [y_test_pred, scores] = (model.predict(x_test)); %1,2,3,4 -  for down, left, right, up
            y_test_pred = str2double(y_test_pred);
            commands=["Initialize", "Go", "Halt"];
            lastcommand = commands(y_test_pred);
            score  = scores(y_test_pred)*100;
            app.ScoreLabel.Text = sprintf('Score = %.2f%%',score);
            global first_ini;
            
            
            if(y_test_pred == 1 && first_ini == 1)
                first_ini = 0;
                if(strcmp(app.StartpositionEditField.Value,"") || strcmp(app.Waypoint1EditField.Value,""))
                    uialert(app.UIFigure, 'Enter start and stop positions','No conditions');
                else


            global kitt;
            global Timer_period;
            start_value = str2double(app.StartpositionEditField.Value);
            stop_value = str2double(app.Waypoint1EditField.Value);
            yline(app.UIAxes,start_value,'--','Start Position');
            hold(app.UIAxes, 'on' )
            yline(app.UIAxes,stop_value,'-.','Stop position');
            app.StartButton.Enable = 'off';
            kitt.VectorY(1) = (-1*start_value + 600)*0.01;
            kitt.VectorX(1) = 300*0.01;
            
            
            Fai = 10
            Fbi = 10
            distance = (start_value - stop_value)*0.01
            %timer_period = 0.05
            if(distance > 4)
                margin = -0.07;
            else
                margin = -0.03 %m
            end
            
            global s_stop;
            global t_stop;
            global v_max;
            
            [s_stop,t_stop,v_max] = controller(Fai, Fbi, distance, Timer_period, margin)
            
            s_stop = s_stop*100 - (600-start_value);
            
            app.TimeuntillBrakingLabel.Text = sprintf('Time Untill Braking: %.2fs',t_stop); 
            
            app.MaximumVelocityLabel.Text = sprintf('Maximum Velocity: %.2fm/s',v_max);  
            app.StopDistancefromwallLabel.Text = sprintf('Stop Distance from wall: %.2fcm',s_stop);  


            start(app.RandTimer);
            global ini_p;
            ini_p = 1;
                    
                    
                end
            elseif(y_test_pred == 2)
                global go;
                global kitt;
                global ini_p;
                if(ini_p == 1)
                    go = 1;
                    app.TimessccLabel.Visible = 'on';
                end
                kitt.initialTime=tic;
            elseif(y_test_pred == 3)
                global halt;
                global go;
                if (go == 1)
                    halt = 1;
                end
                
            end


            app.LastVoiceCommandLabel.Text = append('Last Voice Command: ',lastcommand);
        end

        % Callback function
        function angleKnobValueChanging(app, event)
            value = event.Value;
            global angle
            global kitt
            
            value = ceil(value);
            fakeAxis=100:1:200;
            realAxis=linspace(115,65,length(fakeAxis));
            conv=realAxis(find(value==fakeAxis));

            angle = ceil(conv)
            kitt.angle=angle;
        end

        % Callback function
        function RandomDelaySwitchValueChanged(app, event)
            value = app.RandomDelaySwitch.Value;
            global noise;
            if (strcmpi(value,'On') == 1)
                noise = 1;
                app.NoiseLamp.Color = 'green';
            elseif (strcmpi(value,'Off') == 1)
                noise = 0;
                app.NoiseLamp.Color = 'red';
            end
        end

        % Callback function
        function HaltButtonPushed(app, event)
            global halt;
            halt = 1;
        end

        % Callback function
        function GoButtonPushed(app, event)
            global go;
            global kitt;
            go = 1;
            kitt.initialTime=tic;
        end

        % Callback function
        function CarVisibleSwitchValueChanged(app, event)
            value = app.CarVisibleSwitch.Value;
            global car_visible;
            if (strcmpi(value,'On') == 1)
                car_visible = 1;

            elseif (strcmpi(value,'Off') == 1)
                car_visible = 0;

            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Position of Car')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.xpositionLabel = uilabel(app.UIFigure);
            app.xpositionLabel.Text = 'x position = ';
            app.VelocityGaugeLabel = uilabel(app.UIFigure);
            app.VelocityGaugeLabel.Text = 'Velocity = ';
            app.VelocityGauge = uigauge(app.UIFigure, 'semicircular');
            app.VelocityGauge.Limits = [-50 50];
            app.Waypoint1EditFieldLabel = uilabel(app.UIFigure);
            app.Waypoint1EditFieldLabel.Text = 'Waypoint 1';
            app.Waypoint1EditField = uieditfield(app.UIFigure, 'text');
            app.Waypoint1EditField.Value = '100,100';
            app.StartpositionEditFieldLabel = uilabel(app.UIFigure);
            app.StartpositionEditFieldLabel.Text = 'Start position';
            app.StartpositionEditField = uieditfield(app.UIFigure, 'text');
            app.StartpositionEditField.ValueChangingFcn = createCallbackFcn(app, @StartpositionEditFieldValueChanging, true);
            app.StartpositionEditField.Value = '0,0';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.NewCommandButton = uibutton(app.UIFigure, 'push');
            app.NewCommandButton.ButtonPushedFcn = createCallbackFcn(app, @NewCommandButtonPushed, true);
            app.NewCommandButton.Text = 'New Command';
            app.TimessccLabel = uilabel(app.UIFigure);
            app.TimessccLabel.Visible = 'off';
            app.TimessccLabel.Text = 'Time: ss.cc';
            app.ypositionLabel = uilabel(app.UIFigure);
            app.ypositionLabel.Text = 'y position = ';
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score: ';
            app.ForceLabel = uilabel(app.UIFigure);
            app.ForceLabel.Text = 'Force = ';
            app.ForceGauge = uigauge(app.UIFigure, 'semicircular');
            app.ForceGauge.Limits = [-10 10];
            app.StoppositionEditFieldLabel = uilabel(app.UIFigure);
            app.StoppositionEditFieldLabel.Text = 'Stop position';
            app.StoppositionEditField = uieditfield(app.UIFigure, 'text');
            app.StoppositionEditField.Value = '400,400';
            app.AngleGaugeLabel = uilabel(app.UIFigure);
            app.AngleGaugeLabel.Text = 'Angle = ';
            app.AngleGauge = uigauge(app.UIFigure, 'semicircular');
            app.ChallengeButtonGroup = uibuttongroup(app.UIFigure);
            app.ChallengeButtonGroup.Title = 'Challenge:';
            app.ChallengeAButton = uitogglebutton(app.ChallengeButtonGroup);
            app.ChallengeAButton.Text = 'Challenge A';
            app.ChallengeAButton.Value = true;
            app.ChallengeBButton = uitogglebutton(app.ChallengeButtonGroup);
            app.ChallengeBButton.Text = 'Challenge B';
            app.ChallengeCButton = uitogglebutton(app.ChallengeButtonGroup);
            app.ChallengeCButton.Text = 'Challenge C';
            app.Waypoint2EditFieldLabel = uilabel(app.UIFigure);
            app.Waypoint2EditFieldLabel.Text = 'Waypoint 2';
            app.Waypoint2EditField = uieditfield(app.UIFigure, 'text');
            app.Waypoint2EditField.Value = '100,100';
            app.OperationModeButtonGroup = uibuttongroup(app.UIFigure);
            app.OperationModeButtonGroup.Title = 'Operation Mode:';
            app.ManualButton = uitogglebutton(app.OperationModeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.VoiceCommandButton = uitogglebutton(app.OperationModeButtonGroup);
            app.VoiceCommandButton.Text = 'Voice Command';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end