classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        turnradiusEditField            matlab.ui.control.EditField
        turnradiusEditFieldLabel       matlab.ui.control.Label
        directionEditField             matlab.ui.control.EditField
        directionEditFieldLabel        matlab.ui.control.Label
        ChallengeSwitch                matlab.ui.control.Switch
        ChallengeSwitchLabel           matlab.ui.control.Label
        stoppositionEditField_2        matlab.ui.control.EditField
        stoppositionEditField_2Label   matlab.ui.control.Label
        CarVisibleSwitch               matlab.ui.control.Switch
        CarVisibleSwitchLabel          matlab.ui.control.Label
        ForceGauge                     matlab.ui.control.SemicircularGauge
        ForceGaugeLabel                matlab.ui.control.Label
        DistanceSensorsActivatedLabel  matlab.ui.control.Label
        NoiseLamp                      matlab.ui.control.Lamp
        NoiseLampLabel                 matlab.ui.control.Label
        RandomDelaySwitch              matlab.ui.control.Switch
        RandomDelaySwitchLabel         matlab.ui.control.Label
        ScoreLabel                     matlab.ui.control.Label
        angleKnob                      matlab.ui.control.Knob
        angleKnobLabel                 matlab.ui.control.Label
        ypositionLabel                 matlab.ui.control.Label
        TimessccLabel                  matlab.ui.control.Label
        NewVoiceCommandButton          matlab.ui.control.Button
        DistancetoFinishLabel          matlab.ui.control.Label
        LastVoiceCommandLabel          matlab.ui.control.Label
        initializeButton               matlab.ui.control.Button
        startpositionEditField         matlab.ui.control.EditField
        startpositionEditFieldLabel    matlab.ui.control.Label
        WP1EditField                   matlab.ui.control.EditField
        WP1EditFieldLabel              matlab.ui.control.Label
        velocityGauge                  matlab.ui.control.SemicircularGauge
        velocityGaugeLabel             matlab.ui.control.Label
        StatusLabel                    matlab.ui.control.Label
        xpositionLabel                 matlab.ui.control.Label
        ForceKnob                      matlab.ui.control.Knob
        ForceKnobLabel                 matlab.ui.control.Label
        VelocityLabel                  matlab.ui.control.Label
        KITTLamp                       matlab.ui.control.Lamp
        KITTLampLabel                  matlab.ui.control.Label
        KITTSwitch                     matlab.ui.control.Switch
        KITTSwitchLabel                matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        RandTimer          % Timer object
        PlotLine           % Line object
    end
    

    methods (Access = private)
    
        function RandTimerFcn(app,~,~)
            global g1
            global halt;


            global kitt
            global v
            global force
            global pos
            global time
            global status
            global angle
            global s_stop
            global noise;
            
            kitt %put semicolumn to not show in command line
            
            v(1) = 100*kitt.VectorX(2);
            v(2) = 100*kitt.VectorY(2);
            
            pos(1) = 100*kitt.VectorX(1); %convert axis
            pos(2) = -100*kitt.VectorY(1) + 600;
            
            status = kitt.status;
            time = kitt.time;
            initialTime = kitt.initialTime;
            finalTime = kitt.finalTime;
            
            dis2finish = -1*str2double(app.WP1EditField.Value) + pos(2);
            
            if(noise == 1)
                pause(0.010 + rand(1)*0.02)%delay to simulate the sensor delay
            end
            global go;
            if(go == 1 && halt == 0)
                if(noise == 1)
                    pause(0.010 + rand(1)*0.02)%delay to simulate the delay in setting force
                end
                force = 10
            elseif(v(2) < 15 && halt == 1) %distance sensor
                if(noise == 1)
                    pause(0.010 + rand(1)*0.02)%delay to simulate the delay in setting force
                end
                
                force = 0
                if(v(2) < 0.01) % maybe add: dis2finish < 5 || 
                    kitt.status = 2;
                end
                
            elseif(pos(2) < s_stop && halt == 1)
                if(noise == 1)
                    pause(0.010 + rand(1)*0.02)%delay to simulate the delay in setting force
                end
                force = -10
            
            end
            kitt.force = force;


            v_mag = sqrt(v(1)^2 + v(2)^2); %calculate the magnitude of velocity & acceleration
                        
            app.velocityGauge.Value = v_mag;
            value=sprintf('Velocity = %.2f cm/s',v_mag);
            app.VelocityLabel.Text = (value);
            
            if kitt.status ~= 2
                timeL = toc(initialTime);
            else
                disp("final time")
                timeL = toc(initialTime)-toc(finalTime);
            end
            value = sprintf('Time: %.2f s', timeL);
            app.TimessccLabel.Text = value;


            value=sprintf('X Position = %.2f m',pos(1));
            app.xpositionLabel.Text = (value);
            value=sprintf('Y Position = %.2f m',pos(2));
            app.ypositionLabel.Text = (value);
            

            if (status == -1)
            app.StatusLabel.Text = ('Status = crash occured');
            elseif (status == 1)
                app.StatusLabel.Text = ('Status = ON');
                %kitt.angle = angle;
                %kitt.force = force; % maybe later add to: function ForceKnobValueChanging(app, event)
            elseif(status == 0)
                app.StatusLabel.Text = ('Status = OFF');
            elseif(status == 2)
                app.StatusLabel.Text = ('Status = SUCCEEDED');
            end
            
            
            if(halt == 1)
                app.DistanceSensorsActivatedLabel.Visible = 'on';
            else
                app.DistanceSensorsActivatedLabel.Visible = 'off';
            end
            
            
            force = kitt.force; %update the force and angle after write
            angle = kitt.angle;
            
            app.ForceGauge.Value = force;


            app.DistancetoFinishLabel.Text = sprintf('Distance to Finish = %.2fcm',dis2finish);  

            %pos(end + 1) = ceil(xpos);
            
            plot(app.UIAxes,pos(1),pos(2),'b.')
            hold( app.UIAxes, 'on' )
            
            
            img = imread('car.png');             % Load a sample image 
            %img1 = imrotate(img,90);
            delete(g1);
            global car_visible;
            if (car_visible == 1)
            g1 = image(app.UIAxes,[pos(1)-20 pos(1)+20],[pos(2)-40 pos(2)+40],img);        % Plot the image
            end


       end        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %rectangle(app.UIAxes,'Position',[1,2,5,10],'FaceColor',[0.8 0.8 0.8],'EdgeColor','k',...
    %'LineWidth',3);
            %axis(app.UIAxes, 'off');
            
            waypoint_1_x = 20;
            waypoint_1_y = 100;
            waypoint_2_x = 20;
            waypoint_2_y = 200;
            
            global ini_p;
            ini_p = 0;
            
            global kitt
            kitt = model;
            global v;
            v = [0,0];
            global force;
            force = 0;
            global angle;
            angle = 90;
            
            global direction;
            direction = 90;
            global radius;
            radius = 110;
            
            kitt.force = force; %initial conditions
            kitt.angle = angle;
            
            global Timer_period;
            Timer_period = 0.076;
            
                app.KITTLamp.Color = 'red';
                        % Create timer object
            app.RandTimer = timer(...
                'ExecutionMode', 'fixedRate', ...    % Run timer repeatedly
                'Period', Timer_period, ...                     % Period is 0.1 second
                'BusyMode', 'queue',...              % Queue timer callbacks when busy
                'TimerFcn', @app.RandTimerFcn);      % Specify callback function


%            patch(app.UIAxes,'vertices', [0, 600; 600, 600; 600, 595; 0, 595], ...
%           'faces', [1, 2, 3, 4], ...
%           'FaceColor', 'b', ...
%           'FaceAlpha', 0.5)
           
            hold(app.UIAxes, 'on');
            %plot(app.UIAxes,waypoint_1_x,waypoint_1_y,'r+')
            %plot(app.UIAxes,waypoint_2_x,waypoint_2_y,'r+')
            %plot(app.UIAxes,0,0,'rx')
            %plot(app.UIAxes,0,600,'rx')
            %plot(app.UIAxes,600,0,'rx')
            %plot(app.UIAxes,600,600,'rx')

            xlim(app.UIAxes,[0, 600])
            ylim(app.UIAxes,[0, 600])
            
            global challenge;
            challenge = 1; %1 is challenge A

            
            global first_ini;
            first_ini = 1;
            
            global halt;
            global noise;
            halt = 0;
            noise = 1;
            
            global go;
            go = 0;
            
            
            global car_visible;
            car_visible = 1;
            

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.RandTimer);
            delete(app)
        end

        % Callback function
        function velocityKnobValueChanged(app, event)
            value = app.velocityKnob.Value;
            global v
            v = value;
            value=sprintf('Velocity = %.2f',value);
            app.VelocityLabel.Text = (value);
        end

        % Value changed function: KITTSwitch
        function KITTSwitchValueChanged(app, event)
            value = app.KITTSwitch.Value;
               global kitt
            if (strcmpi(value,'On') == 1)
                kitt.status = 1
                app.KITTLamp.Color = 'green';
            elseif (strcmpi(value,'Off') == 1)
                kitt.status = 0
                app.KITTLamp.Color = 'red';
            end
        end

        % Callback function
        function velocityKnobValueChanging(app, event)
            value = event.Value;

            global v
            v = value;
            value=sprintf('Velocity = %.2f',value);
            app.VelocityLabel.Text = (value);
        end

        % Value changing function: ForceKnob
        function ForceKnobValueChanging(app, event)
            
%             value = event.Value;
%             global force
%             global kitt
%             
%             value = ceil(value);
%             
%             fakeAxis=135:1:165;
%             realAxis=linspace(-10,10,length(fakeAxis));
%             conv=realAxis(find(value==fakeAxis));
% 
%             force = ceil(conv)
%             kitt.force=force;
        end

        % Callback function
        function crashButtonPushed(app, event)
            global kitt
            kitt.status = 1;
        end

        % Value changing function: startpositionEditField
        function startpositionEditFieldValueChanging(app, event)
            changingValue = event.Value;

        end

        % Button pushed function: initializeButton
        function initializeButtonPushed(app, event)
            global kitt;
            global Timer_period;
%             start_value = str2double(app.startpositionEditField.Value);
%             stop_value = str2double(app.WP1EditField.Value);
%             yline(app.UIAxes,start_value,'--','Start Position');
%             hold(app.UIAxes, 'on' )
%             yline(app.UIAxes,stop_value,'-.','Stop position');
%             app.initializeButton.Enable = 'off';
%             kitt.VectorY(1) = (-1*start_value + 600)*0.01;
%             kitt.VectorX(1) = 300*0.01;

out1 = regexp(app.startpositionEditField.Value, ',', 'split')
out1 = str2double(out1)
out2 = regexp(app.WP1EditField.Value, ',', 'split')
out2 = str2double(out2)

out4 = regexp(app.stoppositionEditField_2.Value, ',', 'split')
out4 = str2double(out4)
global radius;
global direction;
global challenge;
global path;

if(challenge == 1) %challenge a
    path = challenge_A_routing(out1, out4, radius, direction)
elseif(challenge == 2)
    path = challenge_B_routing(out1, out2, out4, radius, direction)
end
x_coor = path(1,:);
y_coor = path(2,:);

            plot(app.UIAxes, x_coor, y_coor)
            hold(app.UIAxes, 'on' )
            
            
            %timer_period = 0.05

            %start(app.RandTimer);
        end

        % Button pushed function: NewVoiceCommandButton
        function NewVoiceCommandButtonPushed(app, event)
            Fs = 8000;
            win = 20e-3;
            threshold = 0.1;
            L = Fs*win;
            ov = 0.5*L;
            global force;
            random_name = load("mymodel.mat");
            model = random_name.model;
            mu_train = random_name.mu_train;
            sigma_train = random_name.sigma_train;
            

            recObj = audiorecorder(Fs,16,1); % create audio object, 16 bits resolution
            
            disp('Start speaking...')
            app.NewVoiceCommandButton.BackgroundColor = 'green';
            recordblocking(recObj, 0.5); % do a 2 second recording (blocking)
            disp('End of Recording.');
            app.NewVoiceCommandButton.BackgroundColor = 'white';
            y = getaudiodata(recObj);
            
            
            %plot(y)
            
            
            audiowrite("lastRecording.wav",y,Fs);
            
            x_test = ExtractFeat(y, Fs, L, ov, threshold);

            % standard normalize 
            x_test = (x_test-mu_train)./sigma_train;

            % predic
            [y_test_pred, scores] = (model.predict(x_test)); %1,2,3,4 -  for down, left, right, up
            y_test_pred = str2double(y_test_pred);
            commands=["Initialize", "Go", "Halt"];
            lastcommand = commands(y_test_pred);
            score  = scores(y_test_pred)*100;
            app.ScoreLabel.Text = sprintf('Score = %.2f%%',score);
            global first_ini;
            
            
            if(y_test_pred == 1 && first_ini == 1)
                first_ini = 0;
                if(strcmp(app.startpositionEditField.Value,"") || strcmp(app.WP1EditField.Value,""))
                    uialert(app.UIFigure, 'Enter start and stop positions','No conditions');
                else


            global kitt;
            global Timer_period;
            start_value = str2double(app.startpositionEditField.Value);
            stop_value = str2double(app.WP1EditField.Value);
            yline(app.UIAxes,start_value,'--','Start Position');
            hold(app.UIAxes, 'on' )
            yline(app.UIAxes,stop_value,'-.','Stop position');
            app.initializeButton.Enable = 'off';
            kitt.VectorY(1) = (-1*start_value + 600)*0.01;
            kitt.VectorX(1) = 300*0.01;
            
            
            Fai = 10
            Fbi = 10
            distance = (start_value - stop_value)*0.01
            %timer_period = 0.05
            if(distance > 4)
                margin = -0.07;
            else
                margin = -0.03 %m
            end
            
            global s_stop;
            global t_stop;
            global v_max;
            
            [s_stop,t_stop,v_max] = controller(Fai, Fbi, distance, Timer_period, margin)
            
            s_stop = s_stop*100 - (600-start_value);
            
            app.TimeuntillBrakingLabel.Text = sprintf('Time Untill Braking: %.2fs',t_stop); 
            
            app.MaximumVelocityLabel.Text = sprintf('Maximum Velocity: %.2fm/s',v_max);  
            app.StopDistancefromwallLabel.Text = sprintf('Stop Distance from wall: %.2fcm',s_stop);  


            start(app.RandTimer);
            global ini_p;
            ini_p = 1;
                    
                    
                end
            elseif(y_test_pred == 2)
                global go;
                global kitt;
                global ini_p;
                if(ini_p == 1)
                    go = 1;
                    app.TimessccLabel.Visible = 'on';
                end
                kitt.initialTime=tic;
            elseif(y_test_pred == 3)
                global halt;
                global go;
                if (go == 1)
                    halt = 1;
                end
                
            end


            app.LastVoiceCommandLabel.Text = append('Last Voice Command: ',lastcommand);
        end

        % Value changing function: angleKnob
        function angleKnobValueChanging(app, event)
            value = event.Value;
            global angle
            global kitt
            
            value = ceil(value);
            fakeAxis=100:1:200;
            realAxis=linspace(115,65,length(fakeAxis));
            conv=realAxis(find(value==fakeAxis));

            angle = ceil(conv)
            kitt.angle=angle;
        end

        % Value changed function: RandomDelaySwitch
        function RandomDelaySwitchValueChanged(app, event)
            value = app.RandomDelaySwitch.Value;
            global noise;
            if (strcmpi(value,'On') == 1)
                noise = 1;
                app.NoiseLamp.Color = 'green';
            elseif (strcmpi(value,'Off') == 1)
                noise = 0;
                app.NoiseLamp.Color = 'red';
            end
        end

        % Callback function
        function HaltButtonPushed(app, event)
            global halt;
            halt = 1;
        end

        % Callback function
        function GoButtonPushed(app, event)
            global go;
            global kitt;
            go = 1;
            kitt.initialTime=tic;
        end

        % Value changed function: CarVisibleSwitch
        function CarVisibleSwitchValueChanged(app, event)
            value = app.CarVisibleSwitch.Value;
            global car_visible;
            if (strcmpi(value,'On') == 1)
                car_visible = 1;

            elseif (strcmpi(value,'Off') == 1)
                car_visible = 0;

            end
            
        end

        % Value changed function: ChallengeSwitch
        function ChallengeSwitchValueChanged(app, event)
            value = app.ChallengeSwitch.Value;
            global challenge 
     
            if (strcmpi(value,'A') == 1)
                challenge = 1
            elseif (strcmpi(value,'B') == 1)
                challenge = 2

            end
        end

        % Value changed function: directionEditField
        function directionEditFieldValueChanged(app, event)
            value = app.directionEditField.Value;
            global direction;
            direction = str2double(value)
        end

        % Value changed function: turnradiusEditField
        function turnradiusEditFieldValueChanged(app, event)
            value = app.turnradiusEditField.Value;
            global radius;
            radius = str2double(value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Position of KITT')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.KITTSwitchLabel = uilabel(app.UIFigure);
            app.KITTSwitchLabel.Text = 'KITT';
            app.KITTSwitch = uiswitch(app.UIFigure, 'slider');
            app.KITTSwitch.ValueChangedFcn = createCallbackFcn(app, @KITTSwitchValueChanged, true);
            app.KITTLampLabel = uilabel(app.UIFigure);
            app.KITTLampLabel.Text = 'KITT';
            app.KITTLamp = uilamp(app.UIFigure);
            app.VelocityLabel = uilabel(app.UIFigure);
            app.VelocityLabel.Text = 'Velocity = ';
            app.ForceKnobLabel = uilabel(app.UIFigure);
            app.ForceKnobLabel.Enable = 'off';
            app.ForceKnobLabel.Visible = 'off';
            app.ForceKnobLabel.Text = 'Force';
            app.ForceKnob = uiknob(app.UIFigure, 'continuous');
            app.ForceKnob.Limits = [135 165];
            app.ForceKnob.ValueChangingFcn = createCallbackFcn(app, @ForceKnobValueChanging, true);
            app.ForceKnob.Enable = 'off';
            app.ForceKnob.Visible = 'off';
            app.ForceKnob.Value = 150;
            app.xpositionLabel = uilabel(app.UIFigure);
            app.xpositionLabel.Text = 'x position = ';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Status = ';
            app.velocityGaugeLabel = uilabel(app.UIFigure);
            app.velocityGaugeLabel.Text = 'velocity';
            app.velocityGauge = uigauge(app.UIFigure, 'semicircular');
            app.velocityGauge.Limits = [-50 50];
            app.WP1EditFieldLabel = uilabel(app.UIFigure);
            app.WP1EditFieldLabel.Text = 'WP 1';
            app.WP1EditField = uieditfield(app.UIFigure, 'text');
            app.WP1EditField.Value = '100,100';
            app.startpositionEditFieldLabel = uilabel(app.UIFigure);
            app.startpositionEditFieldLabel.Text = 'start position';
            app.startpositionEditField = uieditfield(app.UIFigure, 'text');
            app.startpositionEditField.ValueChangingFcn = createCallbackFcn(app, @startpositionEditFieldValueChanging, true);
            app.startpositionEditField.Value = '0,0';
            app.initializeButton = uibutton(app.UIFigure, 'push');
            app.initializeButton.ButtonPushedFcn = createCallbackFcn(app, @initializeButtonPushed, true);
            app.initializeButton.Text = 'initialize';
            app.LastVoiceCommandLabel = uilabel(app.UIFigure);
            app.LastVoiceCommandLabel.Text = 'Last Voice Command: ';
            app.DistancetoFinishLabel = uilabel(app.UIFigure);
            app.DistancetoFinishLabel.Text = 'Distance to Finish: ';
            app.NewVoiceCommandButton = uibutton(app.UIFigure, 'push');
            app.NewVoiceCommandButton.ButtonPushedFcn = createCallbackFcn(app, @NewVoiceCommandButtonPushed, true);
            app.NewVoiceCommandButton.Text = 'New Voice Command';
            app.TimessccLabel = uilabel(app.UIFigure);
            app.TimessccLabel.Visible = 'off';
            app.TimessccLabel.Text = 'Time: ss.cc';
            app.ypositionLabel = uilabel(app.UIFigure);
            app.ypositionLabel.Text = 'y position = ';
            app.angleKnobLabel = uilabel(app.UIFigure);
            app.angleKnobLabel.Enable = 'off';
            app.angleKnobLabel.Visible = 'off';
            app.angleKnobLabel.Text = 'angle';
            app.angleKnob = uiknob(app.UIFigure, 'continuous');
            app.angleKnob.Limits = [100 200];
            app.angleKnob.ValueChangingFcn = createCallbackFcn(app, @angleKnobValueChanging, true);
            app.angleKnob.Enable = 'off';
            app.angleKnob.Visible = 'off';
            app.angleKnob.Value = 150;
            app.ScoreLabel = uilabel(app.UIFigure);
            app.ScoreLabel.Text = 'Score: ';
            app.RandomDelaySwitchLabel = uilabel(app.UIFigure);
            app.RandomDelaySwitchLabel.Text = 'Random Delay';
            app.RandomDelaySwitch = uiswitch(app.UIFigure, 'slider');
            app.RandomDelaySwitch.ValueChangedFcn = createCallbackFcn(app, @RandomDelaySwitchValueChanged, true);
            app.RandomDelaySwitch.Value = 'On';
            app.NoiseLampLabel = uilabel(app.UIFigure);
            app.NoiseLampLabel.Text = 'Noise';
            app.NoiseLamp = uilamp(app.UIFigure);
            app.DistanceSensorsActivatedLabel = uilabel(app.UIFigure);
            app.DistanceSensorsActivatedLabel.Visible = 'off';
            app.DistanceSensorsActivatedLabel.Text = 'Distance Sensors Activated!';
            app.ForceGaugeLabel = uilabel(app.UIFigure);
            app.ForceGaugeLabel.Text = 'Force';
            app.ForceGauge = uigauge(app.UIFigure, 'semicircular');
            app.ForceGauge.Limits = [-10 10];
            app.CarVisibleSwitchLabel = uilabel(app.UIFigure);
            app.CarVisibleSwitchLabel.Text = 'Car Visible';
            app.CarVisibleSwitch = uiswitch(app.UIFigure, 'slider');
            app.CarVisibleSwitch.ValueChangedFcn = createCallbackFcn(app, @CarVisibleSwitchValueChanged, true);
            app.CarVisibleSwitch.Value = 'On';
            app.stoppositionEditField_2Label = uilabel(app.UIFigure);
            app.stoppositionEditField_2Label.Text = 'stop position';
            app.stoppositionEditField_2 = uieditfield(app.UIFigure, 'text');
            app.stoppositionEditField_2.Value = '400,400';
            app.ChallengeSwitchLabel = uilabel(app.UIFigure);
            app.ChallengeSwitchLabel.Text = 'Challenge';
            app.ChallengeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ChallengeSwitch.Items = {'A', 'B'};
            app.ChallengeSwitch.ValueChangedFcn = createCallbackFcn(app, @ChallengeSwitchValueChanged, true);
            app.ChallengeSwitch.Value = 'A';
            app.directionEditFieldLabel = uilabel(app.UIFigure);
            app.directionEditFieldLabel.Text = 'direction';
            app.directionEditField = uieditfield(app.UIFigure, 'text');
            app.directionEditField.ValueChangedFcn = createCallbackFcn(app, @directionEditFieldValueChanged, true);
            app.directionEditField.Value = '90';
            app.turnradiusEditFieldLabel = uilabel(app.UIFigure);
            app.turnradiusEditFieldLabel.Text = 'turn radius';
            app.turnradiusEditField = uieditfield(app.UIFigure, 'text');
            app.turnradiusEditField.ValueChangedFcn = createCallbackFcn(app, @turnradiusEditFieldValueChanged, true);
            app.turnradiusEditField.Value = '110';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end